classdef pertexp_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        Image                           matlab.ui.control.Image
        SimulateButton                  matlab.ui.control.Button
        InitialconditionsPanel          matlab.ui.container.Panel
        RecoverednoninfantsEditField    matlab.ui.control.NumericEditField
        RecoverednoninfantsEditFieldLabel  matlab.ui.control.Label
        InfectednoninfantsEditField     matlab.ui.control.NumericEditField
        InfectednoninfantsEditFieldLabel  matlab.ui.control.Label
        VaccinatednoninfantsEditField   matlab.ui.control.NumericEditField
        VaccinatednoninfantsEditFieldLabel  matlab.ui.control.Label
        SusceptiblenoninfantsEditField  matlab.ui.control.NumericEditField
        SusceptiblenoninfantsEditFieldLabel  matlab.ui.control.Label
        RecoveredinfantsEditField       matlab.ui.control.NumericEditField
        RecoveredinfantsLabel           matlab.ui.control.Label
        InfectedinfantsEditField        matlab.ui.control.NumericEditField
        InfectedinfantsLabel            matlab.ui.control.Label
        VaccinatedinfantsEditField      matlab.ui.control.NumericEditField
        VaccinatedinfantsLabel          matlab.ui.control.Label
        SusceptibleinfantsEditField     matlab.ui.control.NumericEditField
        SusceptibleinfantsLabel         matlab.ui.control.Label
        AnnualnumberofnoninfantsreceivingTdapboosterdoseper10000EditField  matlab.ui.control.NumericEditField
        AnnualfractionofnoninfantsreceivingTdapboosterdoseper10000Label  matlab.ui.control.Label
        AnnualinfantsDTaPcoverageEditField  matlab.ui.control.NumericEditField
        AnnualinfantDTaPcoverageLabel   matlab.ui.control.Label
        NoiseSwitch                     matlab.ui.control.Switch
        NoiseSwitchLabel                matlab.ui.control.Label
        VaccinationparametersPanel      matlab.ui.container.Panel
        VaccineinducedimmunityperiodyrEditField  matlab.ui.control.NumericEditField
        VaccineinducedimmunityperiodLabel  matlab.ui.control.Label
        TdapefficacyEditField           matlab.ui.control.NumericEditField
        TdapefficacyLabel               matlab.ui.control.Label
        DTaPefficacyEditField           matlab.ui.control.NumericEditField
        DTaPefficacyLabel               matlab.ui.control.Label
        MaternalvaccinationcoverageEditField  matlab.ui.control.NumericEditField
        MaternalvaccinationcoverageLabel  matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        ControlreproductionnumbermathcalR_cEditField  matlab.ui.control.NumericEditField
        ControlreproductionnumbermathcalR_cLabel  matlab.ui.control.Label
        UIAxes_5                        matlab.ui.control.UIAxes
        UIAxes_4                        matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
     
% GUI APPEARANCE (colors, panels, button style, axes style)

% Set the window title
app.UIFigure.Name = 'PerTexP Graphical User Interface';

% --- Color palette ---
% MainColor is the primary accent color (e.g., titles, borders, main buttons)
% BgColor is the overall background color of the GUI (warm tone)
MainColor = [0, 48/255, 73/255];           % main blue
BgColor   = [0.9922 0.9412 0.8353];        % bright background

% --- Main window background ---
app.UIFigure.Color = BgColor;

% --- Panels: typography + colors ---
app.VaccinationparametersPanel.BackgroundColor = [0.9922 0.9412 0.8353];
app.VaccinationparametersPanel.FontWeight      = 'bold';
app.VaccinationparametersPanel.FontSize        = 14;
app.VaccinationparametersPanel.ForegroundColor = MainColor;

app.InitialconditionsPanel.BackgroundColor = [0.9922 0.9412 0.8353];
app.InitialconditionsPanel.FontWeight      = 'bold';
app.InitialconditionsPanel.FontSize        = 14;
app.InitialconditionsPanel.ForegroundColor = MainColor;

% --- Main action button (Simulate) ---
app.SimulateButton.BackgroundColor = MainColor;
app.SimulateButton.FontColor       = [1 1 1];     % white text
app.SimulateButton.FontWeight      = 'bold';
app.SimulateButton.FontSize        = 14;

% --- Axes appearance ---
app.UIAxes_2.FontSize  = 12;
app.UIAxes_2.LineWidth = 1;
app.UIAxes_2.Box       = 'on';
app.UIAxes_2.XGrid     = 'off';
app.UIAxes_2.YGrid     = 'off';

app.UIAxes_3.FontSize  = 12;
app.UIAxes_3.LineWidth = 1;
app.UIAxes_3.Box       = 'on';
app.UIAxes_3.XGrid     = 'off';
app.UIAxes_3.YGrid     = 'off';

app.UIAxes_4.FontSize  = 12;
app.UIAxes_4.LineWidth = 1;
app.UIAxes_4.Box       = 'on';
app.UIAxes_4.XGrid     = 'off';
app.UIAxes_4.YGrid     = 'off';

app.UIAxes_5.FontSize  = 12;
app.UIAxes_5.LineWidth = 1;
app.UIAxes_5.Box       = 'on';
app.UIAxes_5.XGrid     = 'off';
app.UIAxes_5.YGrid     = 'off';

        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)

 % SIMULATE BUTTON CALLBACK 

try
    %% --- 1) Reading parameters from the GUI (raw user inputs) ---
    p_percent   = app.MaternalvaccinationcoverageEditField.Value;   % e.g., 62.6
    effDTaP_pct = app.DTaPefficacyEditField.Value;                  % e.g., 83
    effTdap_pct = app.TdapefficacyEditField.Value;                  % e.g., 92
    vax_imm_year = app.VaccineinducedimmunityperiodyrEditField.Value; % e.g., 10
    psi1_pct    = app.AnnualinfantsDTaPcoverageEditField.Value;     % e.g., 94.7
    psi2_per10k = app.AnnualnumberofnoninfantsreceivingTdapboosterdoseper10000EditField.Value; % e.g., 16.5

    %% --- Converting GUI inputs into model-ready quantities ---
    gui = struct();
    gui.p           = p_percent/100;
    gui.sigma1      = 1 - effDTaP_pct/100;
    gui.sigma2      = 1 - effTdap_pct/100;
    gui.vax_imm_year = vax_imm_year;
    gui.psi1_ann    = psi1_pct/100;
    gui.psi2_ann    = psi2_per10k/10000;

    % Read the noise option from a GUI switch.
    % The switch is assumed to return 'On'/'Off'.
    gui.noiseOn = strcmp(app.NoiseSwitch.Value, 'On');

    %% --- 2) Initial conditions (read from GUI fields) ---
    %   x0 = [S1; V1; I1; S2; V2; I2]
    S1 = app.SusceptibleinfantsEditField.Value;
    V1 = app.VaccinatedinfantsEditField.Value;
    I1 = app.InfectedinfantsEditField.Value;

    S2 = app.SusceptiblenoninfantsEditField.Value;
    V2 = app.VaccinatednoninfantsEditField.Value;
    I2 = app.InfectednoninfantsEditField.Value;

    gui.x0 = [S1; V1; I1; S2; V2; I2];


    %% --- Consistency checks ---

% --- Demography / population sizes (required for the consistency checks)---
N  = 58971230;              % total Italian population in 2024
N1 = 380630;                % infants (< 1 year)
N2 = N - N1;                % non-infants (>= 1 year)

% 1) Initial conditions
if numel(gui.x0) ~= 6
    error('Missing initial conditions: x0 must contain 6 values [S1; V1; I1; S2; V2; I2].');
end
if any(~isfinite(gui.x0))
    error('Invalid initial conditions: NaN/Inf detected in x0.');
end
if any(gui.x0 < 0)
    error('Invalid initial conditions: negative values detected in x0.');
end
if sum(gui.x0(1:3)) > N1
    error('Inconsistent initial conditions: S1+V1+I1 (= %.0f) exceeds N1 (= %.0f).', sum(gui.x0(1:3)), N1);
end
if sum(gui.x0(4:6)) > N2
    error('Inconsistent initial conditions: S2+V2+I2 (= %.0f) exceeds N2 (= %.0f).', sum(gui.x0(4:6)), N2);
end

% 2) GUI parameters (negativity + range checks)
par_names = {'maternal vaccination coverage',...
    'annual infants DTaP coverage',...
    'annual fraction of non-infants receving Tdap booster dose (per 10000)',...
    'DTaP efficacy (%)',...
    'Tdap efficacy (%)',...
    'vaccine--induced immunity period (yr)'};
par_vals  = [ p_percent,  psi1_pct,  psi2_per10k,  effDTaP_pct,   effTdap_pct,  vax_imm_year ];

if any(~isfinite(par_vals))
    idx = find(~isfinite(par_vals));
    error('Parameters contain NaN/Inf: %s.', strjoin(par_names(idx), ', '));
end

% check negativity (all must be >= 0)
idx = find(par_vals < 0);
if ~isempty(idx)
    error('Negative parameters: please check %s.', strjoin(par_names(idx), ', '));
end

% 2) Percent parameters in [0,100]
prob_names = {'maternal vaccination coverage','annual infants DTaP coverage','DTaP efficacy (%)','Tdap efficacy (%)'};
prob_vals = [ p_percent, psi1_pct, effDTaP_pct, effTdap_pct ];
idx = find(prob_vals > 100);
if ~isempty(idx)
    error('Percent inputs must be in [0,100]. Please check %s.', ...
          strjoin(prob_names(idx), ', '));
end

% Booster per 10,000 in [0, 10000]
if psi2_per10k > 10000
    error('Booster uptake (per 10000) must be in [0, 10000]. You entered %.2f.', psi2_per10k);
end

% check strictly positive duration 
if vax_imm_year <= 0 
    error('Vaccine--induced immunity period must be > 0.'); 
end


    %% --- 3) Running the simulation ---
    % pertexp_run(gui) should:
    %   - build parameters from gui struct
    %   - simulate (year-by-year with optional noise)
    %   - return annual relevant quantities + control reproduction number (RC)
    out = pertexp_run(gui);

    %% --- 4) Display Rc in the GUI output field ---
    app.ControlreproductionnumbermathcalR_cEditField.Value = out.RC;

    %% --- 5) Plot on the 4 axes of the GUI (bar plots) ---
    year = 5;
    year_vector = 1:year;

    % Bar colors (kept consistent across plots)
    c1 = [0.62, 0.80, 0.94];   % light blue (infants)
    c2 = [0.99, 0.70, 0.40];   % orange     (non-infants)

    %----------------------------------------------------------------------
    % (A) Infections: infants
    %----------------------------------------------------------------------
    cla(app.UIAxes_3);
    bar(app.UIAxes_3, year_vector, out.inc1_annual, 'FaceColor', c1);

    xticks(app.UIAxes_3, year_vector);
    xticklabels(app.UIAxes_3, year_vector);

    app.UIAxes_3.YLimMode = 'auto';
    app.UIAxes_3.YTickMode = 'auto';
    app.UIAxes_3.YTickLabelMode = 'auto';

    % Y label (LaTeX)
    ylabel(app.UIAxes_3, 'Infected infants', Interpreter='latex');

    % Add "headroom": a small extra margin above the maximum bar height
    ymax = max(out.inc1_annual);
    if ymax <= 0
        ylim(app.UIAxes_3, [0 1]);
    else
        ylim(app.UIAxes_3, [0, 1.10*ymax]);   % +10% space
    end

    %----------------------------------------------------------------------
    % (B) Infections: non-infants
    %----------------------------------------------------------------------
    cla(app.UIAxes_2);
    bar(app.UIAxes_2, year_vector, out.inc2_annual, 'FaceColor', c2);

    xticks(app.UIAxes_2, year_vector);
    xticklabels(app.UIAxes_2, year_vector);

    app.UIAxes_2.YLimMode = 'auto';
    app.UIAxes_2.YTickMode = 'auto';
    app.UIAxes_2.YTickLabelMode = 'auto';

    ylabel(app.UIAxes_2, 'Infected non--infants', Interpreter='latex');

    ymax = max(out.inc2_annual);
    if ymax <= 0
        ylim(app.UIAxes_2, [0 1]);
    else
        ylim(app.UIAxes_2, [0, 1.10*ymax]);   % +10% space
    end

    %----------------------------------------------------------------------
    % (C) Vaccinations: infants (%)
    %----------------------------------------------------------------------
    cla(app.UIAxes_4);
    bar(app.UIAxes_4, year_vector, out.vax1_annual, 'FaceColor', c1);

    xticks(app.UIAxes_4, year_vector);
    xticklabels(app.UIAxes_4, year_vector);

    app.UIAxes_4.YLimMode = 'auto';
    app.UIAxes_4.YTickMode = 'auto';
    app.UIAxes_4.YTickLabelMode = 'auto';

    ylabel(app.UIAxes_4, 'Infant vaccinations (\%)', Interpreter='latex');

    ymax = max(out.vax1_annual);
    if ymax <= 0
        ylim(app.UIAxes_4, [0 1]);
    else
        ylim(app.UIAxes_4, [0, 1.10*ymax]);   % +10% space
    end

    %----------------------------------------------------------------------
    % (D) Vaccinations: non-infants (per 10,000)
    %----------------------------------------------------------------------
    cla(app.UIAxes_5);
    bar(app.UIAxes_5, year_vector, out.vax2_annual, 'FaceColor', c2);

    xticks(app.UIAxes_5, year_vector);
    xticklabels(app.UIAxes_5, year_vector);

    app.UIAxes_5.YLimMode = 'auto';
    app.UIAxes_5.YTickMode = 'auto';
    app.UIAxes_5.YTickLabelMode = 'auto';

    ylabel(app.UIAxes_5, 'Non--infant boosters (per 10\,000)', Interpreter='latex');

    ymax = max(out.vax2_annual);
    if ymax <= 0
        ylim(app.UIAxes_5, [0 1]);
    else
        ylim(app.UIAxes_5, [0, 1.10*ymax]);   % +10% space
    end

    %% Optional: export the app "screenshot" for the paper
   % exportapp(app.UIFigure,'PerTexP_GUI.pdf')

catch ME
    % If anything fails (e.g., invalid inputs or runtime error), show a dialog.
    uialert(app.UIFigure, ME.message, 'Error simulation');
end
   
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {563, 563};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {356, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.VaccinationparametersPanel = uipanel(app.LeftPanel);
            app.VaccinationparametersPanel.Title = 'Vaccination parameters';
            app.MaternalvaccinationcoverageLabel = uilabel(app.VaccinationparametersPanel);
            app.MaternalvaccinationcoverageLabel.Interpreter = 'latex';
            app.MaternalvaccinationcoverageLabel.Text = {'Maternal$\,$vaccination\'; 'coverage'};
            app.MaternalvaccinationcoverageEditField = uieditfield(app.VaccinationparametersPanel, 'numeric');
            app.MaternalvaccinationcoverageEditField.Value = 62.6;
            app.DTaPefficacyLabel = uilabel(app.VaccinationparametersPanel);
            app.DTaPefficacyLabel.Interpreter = 'latex';
            app.DTaPefficacyLabel.Text = 'DTaP\,efficacy\,(\%)';
            app.DTaPefficacyEditField = uieditfield(app.VaccinationparametersPanel, 'numeric');
            app.DTaPefficacyEditField.Value = 83;
            app.TdapefficacyLabel = uilabel(app.VaccinationparametersPanel);
            app.TdapefficacyLabel.Interpreter = 'latex';
            app.TdapefficacyLabel.Text = 'Tdap\,efficacy\,(\%)';
            app.TdapefficacyEditField = uieditfield(app.VaccinationparametersPanel, 'numeric');
            app.TdapefficacyEditField.Value = 92;
            app.VaccineinducedimmunityperiodLabel = uilabel(app.VaccinationparametersPanel);
            app.VaccineinducedimmunityperiodLabel.Interpreter = 'latex';
            app.VaccineinducedimmunityperiodLabel.Text = {'Vaccine--induced\'; 'immunity$\,$period$\,$(yr)'};
            app.VaccineinducedimmunityperiodyrEditField = uieditfield(app.VaccinationparametersPanel, 'numeric');
            app.VaccineinducedimmunityperiodyrEditField.Value = 10;
            app.NoiseSwitchLabel = uilabel(app.LeftPanel);
            app.NoiseSwitchLabel.Text = 'Noise';
            app.NoiseSwitch = uiswitch(app.LeftPanel, 'slider');
            app.AnnualinfantDTaPcoverageLabel = uilabel(app.LeftPanel);
            app.AnnualinfantDTaPcoverageLabel.Interpreter = 'latex';
            app.AnnualinfantDTaPcoverageLabel.Text = {'Annual$\,$infants\'; 'DTaP$\,$coverage$\,$(\%)'};
            app.AnnualinfantsDTaPcoverageEditField = uieditfield(app.LeftPanel, 'numeric');
            app.AnnualinfantsDTaPcoverageEditField.Value = 94.7;
            app.AnnualfractionofnoninfantsreceivingTdapboosterdoseper10000Label = uilabel(app.LeftPanel);
            app.AnnualfractionofnoninfantsreceivingTdapboosterdoseper10000Label.Interpreter = 'latex';
            app.AnnualfractionofnoninfantsreceivingTdapboosterdoseper10000Label.Text = {'Annual$\,$number\'; 'of$\,$non--infants\'; 'receiving$\,$Tdap$\,$booster\'; 'dose$\,$(per $\,$$10\,000$)'};
            app.AnnualnumberofnoninfantsreceivingTdapboosterdoseper10000EditField = uieditfield(app.LeftPanel, 'numeric');
            app.AnnualnumberofnoninfantsreceivingTdapboosterdoseper10000EditField.Value = 16.5;
            app.InitialconditionsPanel = uipanel(app.LeftPanel);
            app.InitialconditionsPanel.Title = 'Initial conditions';
            app.SusceptibleinfantsLabel = uilabel(app.InitialconditionsPanel);
            app.SusceptibleinfantsLabel.Interpreter = 'latex';
            app.SusceptibleinfantsLabel.Text = {'Susceptible\'; 'infants'};
            app.SusceptibleinfantsEditField = uieditfield(app.InitialconditionsPanel, 'numeric');
            app.SusceptibleinfantsEditField.Value = 63001;
            app.VaccinatedinfantsLabel = uilabel(app.InitialconditionsPanel);
            app.VaccinatedinfantsLabel.Interpreter = 'latex';
            app.VaccinatedinfantsLabel.Text = {'Vaccinated\'; 'infants'};
            app.VaccinatedinfantsEditField = uieditfield(app.InitialconditionsPanel, 'numeric');
            app.VaccinatedinfantsEditField.Value = 317620;
            app.InfectedinfantsLabel = uilabel(app.InitialconditionsPanel);
            app.InfectedinfantsLabel.Interpreter = 'latex';
            app.InfectedinfantsLabel.Text = {'Infected\'; 'infants'};
            app.InfectedinfantsEditField = uieditfield(app.InitialconditionsPanel, 'numeric');
            app.InfectedinfantsEditField.Value = 5;
            app.RecoveredinfantsLabel = uilabel(app.InitialconditionsPanel);
            app.RecoveredinfantsLabel.Interpreter = 'latex';
            app.RecoveredinfantsLabel.Text = {'Recovered\'; 'infants'};
            app.RecoveredinfantsEditField = uieditfield(app.InitialconditionsPanel, 'numeric');
            app.SusceptiblenoninfantsEditFieldLabel = uilabel(app.InitialconditionsPanel);
            app.SusceptiblenoninfantsEditFieldLabel.Interpreter = 'latex';
            app.SusceptiblenoninfantsEditFieldLabel.Text = {'Susceptible\'; 'non--infants'};
            app.SusceptiblenoninfantsEditField = uieditfield(app.InitialconditionsPanel, 'numeric');
            app.SusceptiblenoninfantsEditField.Value = 54265000;
            app.VaccinatednoninfantsEditFieldLabel = uilabel(app.InitialconditionsPanel);
            app.VaccinatednoninfantsEditFieldLabel.Interpreter = 'latex';
            app.VaccinatednoninfantsEditFieldLabel.Text = {'Vaccinated\'; 'non--infants'};
            app.VaccinatednoninfantsEditField = uieditfield(app.InitialconditionsPanel, 'numeric');
            app.VaccinatednoninfantsEditField.Value = 4321600;
            app.InfectednoninfantsEditFieldLabel = uilabel(app.InitialconditionsPanel);
            app.InfectednoninfantsEditFieldLabel.Interpreter = 'latex';
            app.InfectednoninfantsEditFieldLabel.Text = {'Infected\'; 'non--infants'};
            app.InfectednoninfantsEditField = uieditfield(app.InitialconditionsPanel, 'numeric');
            app.InfectednoninfantsEditField.Value = 15;
            app.RecoverednoninfantsEditFieldLabel = uilabel(app.InitialconditionsPanel);
            app.RecoverednoninfantsEditFieldLabel.Interpreter = 'latex';
            app.RecoverednoninfantsEditFieldLabel.Text = {'Recovered\'; 'non--infants'};
            app.RecoverednoninfantsEditField = uieditfield(app.InitialconditionsPanel, 'numeric');
            app.RecoverednoninfantsEditField.Value = 4064;
            app.SimulateButton = uibutton(app.LeftPanel, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.Image = uiimage(app.LeftPanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'pertexp_logo.png');
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes_2 = uiaxes(app.RightPanel);
            title(app.UIAxes_2, 'Annual cumulative non-infant infections')
            xlabel(app.UIAxes_2, 'Year')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.XTickLabel = '';
            app.UIAxes_2.YTick = [];
            app.UIAxes_2.YTickLabel = '';
            app.UIAxes_3 = uiaxes(app.RightPanel);
            title(app.UIAxes_3, 'Annual cumulative infant infections')
            xlabel(app.UIAxes_3, 'Year')
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.XTickLabel = '';
            app.UIAxes_3.YTick = [];
            app.UIAxes_3.YTickLabel = '';
            app.UIAxes_4 = uiaxes(app.RightPanel);
            title(app.UIAxes_4, 'Annual cumulative infant vaccinations')
            xlabel(app.UIAxes_4, 'Year')
            app.UIAxes_4.XTick = [];
            app.UIAxes_4.XTickLabel = '';
            app.UIAxes_4.YTick = [];
            app.UIAxes_4.YTickLabel = '';
            app.UIAxes_5 = uiaxes(app.RightPanel);
            title(app.UIAxes_5, 'Annual cumulative non-infant boosters')
            xlabel(app.UIAxes_5, 'Year')
            app.UIAxes_5.XTick = [];
            app.UIAxes_5.XTickLabel = '';
            app.UIAxes_5.YTick = [];
            app.UIAxes_5.YTickLabel = '';
            app.ControlreproductionnumbermathcalR_cLabel = uilabel(app.RightPanel);
            app.ControlreproductionnumbermathcalR_cLabel.Interpreter = 'latex';
            app.ControlreproductionnumbermathcalR_cLabel.Text = 'Control$\,$reproduction$\,$number,$\,$$\mathcal R_c$';
            app.ControlreproductionnumbermathcalR_cEditField = uieditfield(app.RightPanel, 'numeric');
            app.ControlreproductionnumbermathcalR_cEditField.Limits = [0 Inf];
            app.ControlreproductionnumbermathcalR_cEditField.AllowEmpty = 'on';
            app.ControlreproductionnumbermathcalR_cEditField.Editable = 'off';
            app.ControlreproductionnumbermathcalR_cEditField.Value = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = pertexp_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end